classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel_2                    matlab.ui.container.Panel
        CancelButton               matlab.ui.control.Button
        OkButton                   matlab.ui.control.Button
        NameEditField              matlab.ui.control.EditField
        NameEditFieldLabel         matlab.ui.control.Label
        PlaySwitch                 matlab.ui.control.Switch
        PlaySwitchLabel            matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        Button_43                  matlab.ui.control.Button
        Button_42                  matlab.ui.control.Button
        Button_41                  matlab.ui.control.Button
        Button_40                  matlab.ui.control.Button
        Button_39                  matlab.ui.control.Button
        Button_38                  matlab.ui.control.Button
        Button_37                  matlab.ui.control.Button
        Button_36                  matlab.ui.control.Button
        Button_35                  matlab.ui.control.Button
        Button_34                  matlab.ui.control.Button
        Button_33                  matlab.ui.control.Button
        Button_32                  matlab.ui.control.Button
        Button_31                  matlab.ui.control.Button
        Button_30                  matlab.ui.control.Button
        Button_29                  matlab.ui.control.Button
        Button_28                  matlab.ui.control.Button
        Button_27                  matlab.ui.control.Button
        Button_26                  matlab.ui.control.Button
        Button_25                  matlab.ui.control.Button
        Button_24                  matlab.ui.control.Button
        Button_23                  matlab.ui.control.Button
        Button_22                  matlab.ui.control.Button
        Button_21                  matlab.ui.control.Button
        Button_20                  matlab.ui.control.Button
        Button_19                  matlab.ui.control.Button
        FrequenceButtonGroup       matlab.ui.container.ButtonGroup
        Button_3                   matlab.ui.control.RadioButton
        Button_2                   matlab.ui.control.RadioButton
        Button                     matlab.ui.control.RadioButton
        Lamp                       matlab.ui.control.Lamp
        LoadButton                 matlab.ui.control.Button
        FormesdondesDropDown       matlab.ui.control.DropDown
        FormesdondesDropDownLabel  matlab.ui.control.Label
        ExporttoWavButton          matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        y
        fs
        forme
        file_name
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,~] = uigetfile('*.wav');
            if isequal(file,0)
            else
                [app.y,app.fs]=audioread(string(file));
                soundsc(app.y,app.fs);
            end
        end

        % Value changed function: FormesdondesDropDown
        function FormesdondesDropDownValueChanged(app, event)
            value = app.FormesdondesDropDown.Value;
            switch(value)
                case 'Default'
                    app.forme=-1;
                case 'Carré'
                    app.forme=1;
                case 'Triangulaire'
                    app.forme=0;
                case 'Crénau'
                    app.forme=2;
            end
            
        end

        % Value changed function: PlaySwitch
        function PlaySwitchValueChanged(app, event)
            app.y=[0.4000 0,420];
            app.fs=8000;
            value = app.PlaySwitch.Value;
            switch(value)
                case 'Off'
                    app.Lamp.Color = [1 0 0];
                case 'On'
                    app.Lamp.Color = [0 1 0];
            end
        end

        % Button pushed function: ExporttoWavButton
        function ExporttoWavButtonPushed(app, event)
            app.Panel_2.Visible = true;

        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            app.file_name = char(app.NameEditField.Value);
            audiowrite(append(app.file_name,'.wav'),app.y,app.fs);
            app.Panel_2.Visible = false;
            
        end

        % Selection changed function: FrequenceButtonGroup
        function FrequenceButtonGroupSelectionChanged(app, event)
            selectedButton = app.FrequenceButtonGroup.SelectedObject;
            switch(selectedButton)
                case app.Button
                    app.fs=8000;
                case app.Button_2
                    app.fs=16000;
                case app.Button_3
                    app.fs=32000;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Panel_2.Visible = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes2, 'Z')
            app.ExporttoWavButton = uibutton(app.UIFigure, 'push');
            app.ExporttoWavButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoWavButtonPushed, true);
            app.ExporttoWavButton.Text = 'Export to Wav';
            app.FormesdondesDropDownLabel = uilabel(app.UIFigure);
            app.FormesdondesDropDownLabel.Text = 'Formes d''ondes';
            app.FormesdondesDropDown = uidropdown(app.UIFigure);
            app.FormesdondesDropDown.Items = {'Default', 'Carré', 'Triangulaire', 'Crénau'};
            app.FormesdondesDropDown.ValueChangedFcn = createCallbackFcn(app, @FormesdondesDropDownValueChanged, true);
            app.FormesdondesDropDown.Value = 'Default';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Lamp = uilamp(app.UIFigure);
            app.FrequenceButtonGroup = uibuttongroup(app.UIFigure);
            app.FrequenceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FrequenceButtonGroupSelectionChanged, true);
            app.FrequenceButtonGroup.Title = 'Frequence';
            app.Button = uiradiobutton(app.FrequenceButtonGroup);
            app.Button.Text = '8000';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.FrequenceButtonGroup);
            app.Button_2.Text = '16000';
            app.Button_3 = uiradiobutton(app.FrequenceButtonGroup);
            app.Button_3.Text = '32000';
            app.Panel = uipanel(app.UIFigure);
            app.Button_19 = uibutton(app.Panel, 'push');
            app.Button_19.Text = '';
            app.Button_20 = uibutton(app.Panel, 'push');
            app.Button_20.Text = '';
            app.Button_21 = uibutton(app.Panel, 'push');
            app.Button_21.Text = '';
            app.Button_22 = uibutton(app.Panel, 'push');
            app.Button_22.Text = '';
            app.Button_23 = uibutton(app.Panel, 'push');
            app.Button_23.Text = '';
            app.Button_24 = uibutton(app.Panel, 'push');
            app.Button_24.Text = '';
            app.Button_25 = uibutton(app.Panel, 'push');
            app.Button_25.Text = '';
            app.Button_26 = uibutton(app.Panel, 'push');
            app.Button_26.Text = '';
            app.Button_27 = uibutton(app.Panel, 'push');
            app.Button_27.Text = '';
            app.Button_28 = uibutton(app.Panel, 'push');
            app.Button_28.Text = '';
            app.Button_29 = uibutton(app.Panel, 'push');
            app.Button_29.Text = '';
            app.Button_30 = uibutton(app.Panel, 'push');
            app.Button_30.Text = '';
            app.Button_31 = uibutton(app.Panel, 'push');
            app.Button_31.Text = '';
            app.Button_32 = uibutton(app.Panel, 'push');
            app.Button_32.Text = '';
            app.Button_33 = uibutton(app.Panel, 'push');
            app.Button_33.Text = '';
            app.Button_34 = uibutton(app.Panel, 'push');
            app.Button_34.Text = '';
            app.Button_35 = uibutton(app.Panel, 'push');
            app.Button_35.Text = '';
            app.Button_36 = uibutton(app.Panel, 'push');
            app.Button_36.Text = '';
            app.Button_37 = uibutton(app.Panel, 'push');
            app.Button_37.Text = '';
            app.Button_38 = uibutton(app.Panel, 'push');
            app.Button_38.Text = '';
            app.Button_39 = uibutton(app.Panel, 'push');
            app.Button_39.Text = '';
            app.Button_40 = uibutton(app.Panel, 'push');
            app.Button_40.Text = '';
            app.Button_41 = uibutton(app.Panel, 'push');
            app.Button_41.Text = '';
            app.Button_42 = uibutton(app.Panel, 'push');
            app.Button_42.Text = '';
            app.Button_43 = uibutton(app.Panel, 'push');
            app.Button_43.Text = '';
            app.PlaySwitchLabel = uilabel(app.UIFigure);
            app.PlaySwitchLabel.Text = 'Play';
            app.PlaySwitch = uiswitch(app.UIFigure, 'slider');
            app.PlaySwitch.ValueChangedFcn = createCallbackFcn(app, @PlaySwitchValueChanged, true);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Visible = 'off';
            app.NameEditFieldLabel = uilabel(app.Panel_2);
            app.NameEditFieldLabel.Text = 'Name :';
            app.NameEditField = uieditfield(app.Panel_2, 'text');
            app.OkButton = uibutton(app.Panel_2, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.Panel_2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end